classdef SimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope_4         matlab.ui.scope.TimeScope
        TimeScope_3         matlab.ui.scope.TimeScope
        TimeScope_2         matlab.ui.scope.TimeScope
        TimeScope           matlab.ui.scope.TimeScope
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Radians to Degrees:1'};
            app.TimeScope.Title = 'PendAngle';
            app.TimeScope.YLabel = 'Angle';
            app.TimeScope.YLimits = [-400 400];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'From9:1'};
            app.TimeScope_2.Title = 'PendVel';
            app.TimeScope_2.YLabel = 'Velocity';
            app.TimeScope_2.YLimits = [-30 30];
            app.TimeScope_2.YLimitsMethod = 'manual';
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.LegendNames = {'Radians to Degrees1:1'};
            app.TimeScope_3.Title = 'MotorAngle';
            app.TimeScope_3.YLabel = 'Angle';
            app.TimeScope_3.YLimits = [-400 400];
            app.TimeScope_3.YLimitsMethod = 'manual';
            app.TimeScope_4 = uitimescope(app.UIFigure);
            app.TimeScope_4.LegendNames = {'From11:1'};
            app.TimeScope_4.Title = 'MotorVel';
            app.TimeScope_4.YLabel = 'Velocity';
            app.TimeScope_4.YLimits = [-15 15];
            app.TimeScope_4.YLimitsMethod = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Radians to Degrees:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From9:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Radians to Degrees1:1'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From11:1'], app.TimeScope_4);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimGUI

            % Associate the Simulink Model
            app.Simulation = simulation('Full_Simulation');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end